classdef designImageRetrieval < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RicercaimmaginisimiliButton  matlab.ui.control.Button
        ImmaginecaricataPanel        matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        ImmaginiDatabasePanel        matlab.ui.container.Panel
        Image_52                     matlab.ui.control.Image
        Image_51                     matlab.ui.control.Image
        Image_50                     matlab.ui.control.Image
        Image_49                     matlab.ui.control.Image
        Image_48                     matlab.ui.control.Image
        Image_47                     matlab.ui.control.Image
        Image_46                     matlab.ui.control.Image
        Image_45                     matlab.ui.control.Image
        Image_44                     matlab.ui.control.Image
        Image_43                     matlab.ui.control.Image
        Image_42                     matlab.ui.control.Image
        Image_41                     matlab.ui.control.Image
        Image_40                     matlab.ui.control.Image
        Image_39                     matlab.ui.control.Image
        Image_38                     matlab.ui.control.Image
        Image_37                     matlab.ui.control.Image
        Image_36                     matlab.ui.control.Image
        Image_35                     matlab.ui.control.Image
        Image_34                     matlab.ui.control.Image
        Image_33                     matlab.ui.control.Image
        Image_32                     matlab.ui.control.Image
        Image_31                     matlab.ui.control.Image
        Image_30                     matlab.ui.control.Image
        Image_29                     matlab.ui.control.Image
        Image_28                     matlab.ui.control.Image
        Image_27                     matlab.ui.control.Image
        Image_26                     matlab.ui.control.Image
        Image_25                     matlab.ui.control.Image
        Image_24                     matlab.ui.control.Image
        Image_23                     matlab.ui.control.Image
        Image_22                     matlab.ui.control.Image
        Image_21                     matlab.ui.control.Image
        Image_20                     matlab.ui.control.Image
        Image_19                     matlab.ui.control.Image
        Image_18                     matlab.ui.control.Image
        Image_17                     matlab.ui.control.Image
        Image_16                     matlab.ui.control.Image
        Image_15                     matlab.ui.control.Image
        Image_14                     matlab.ui.control.Image
        Image_13                     matlab.ui.control.Image
        Image_12                     matlab.ui.control.Image
        Image_11                     matlab.ui.control.Image
        Image_10                     matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        btn_ricercaImmagini          matlab.ui.control.Button
        ImmaginiSimiliPanel          matlab.ui.container.Panel
        MarianiFilippoRoselliPaoloPetrosilliMarcoLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btn_ricercaImmagini
        function btn_ricercaImmaginiButtonPushed(app, event)
                [query_fname, query_pathname] = uigetfile('*.jpg', 'Selezione l imamagine che vuoi ricercare');    
                query_fullpath = strcat(query_pathname, query_fname);
                [pathstr, name, ext] = fileparts(query_fullpath); 
                queryImage = imread( fullfile( pathstr, strcat(name, ext) ) );
                ax = app.UIAxes
                imshow(queryImage, 'Parent', ax);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'On relevance feedback and similarity measure for image retrieval with synergetic neural nets';
            app.MarianiFilippoRoselliPaoloPetrosilliMarcoLabel = uilabel(app.UIFigure);
            app.MarianiFilippoRoselliPaoloPetrosilliMarcoLabel.Text = {'Mariani Filippo'; 'Roselli Paolo'; 'Petrosilli Marco'};
            app.ImmaginiSimiliPanel = uipanel(app.UIFigure);
            app.ImmaginiSimiliPanel.Title = 'Immagini Simili';
            app.btn_ricercaImmagini = uibutton(app.UIFigure, 'push');
            app.btn_ricercaImmagini.ButtonPushedFcn = createCallbackFcn(app, @btn_ricercaImmaginiButtonPushed, true);
            app.btn_ricercaImmagini.Tag = 'btn_BrowseImage';
            app.btn_ricercaImmagini.Text = 'Carica un immagine';
            app.ImmaginiDatabasePanel = uipanel(app.UIFigure);
            app.ImmaginiDatabasePanel.Title = 'Immagini Database';
            app.Image = uiimage(app.ImmaginiDatabasePanel);
            app.Image_10 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_11 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_12 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_13 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_14 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_15 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_16 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_17 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_18 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_19 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_20 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_21 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_22 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_23 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_24 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_25 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_26 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_27 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_28 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_29 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_30 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_31 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_32 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_33 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_34 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_35 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_36 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_37 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_38 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_39 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_40 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_41 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_42 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_43 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_44 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_45 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_46 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_47 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_48 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_49 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_50 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_51 = uiimage(app.ImmaginiDatabasePanel);
            app.Image_52 = uiimage(app.ImmaginiDatabasePanel);
            app.ImmaginecaricataPanel = uipanel(app.UIFigure);
            app.ImmaginecaricataPanel.Title = 'Immagine caricata';
            app.UIAxes = uiaxes(app.ImmaginecaricataPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.RicercaimmaginisimiliButton = uibutton(app.UIFigure, 'push');
            app.RicercaimmaginisimiliButton.Text = 'Ricerca immagini simili';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = designImageRetrieval

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end